classdef OpzioniApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OpzioniScreen               matlab.ui.Figure
        ConfermaButton              matlab.ui.control.Button
        MinimoBetaEditFieldLabel    matlab.ui.control.Label
        MinimoBetaEditField         matlab.ui.control.EditField
        MassimoBetaEditFieldLabel   matlab.ui.control.Label
        MassimoBetaEditField        matlab.ui.control.EditField
        MinimoSigmaEditFieldLabel   matlab.ui.control.Label
        MinimoSigmaEditField        matlab.ui.control.EditField
        MassimoSigmaEditFieldLabel  matlab.ui.control.Label
        MassimoSigmaEditField       matlab.ui.control.EditField
        MinimoGammaEditFieldLabel   matlab.ui.control.Label
        MinimoGammaEditField        matlab.ui.control.EditField
        MassimoGammaEditFieldLabel  matlab.ui.control.Label
        MassimoGammaEditField       matlab.ui.control.EditField
    end

    
    properties (Access = private)
        CallingApp; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.CallingApp=MainApp;
            app.MinimoBetaEditField.Value=int2str(app.CallingApp.BetaMinimum);
            app.MassimoBetaEditField.Value=int2str(app.CallingApp.BetaMaximum);
            app.MinimoSigmaEditField.Value=int2str(app.CallingApp.SigmaMinimum);
            app.MassimoSigmaEditField.Value=int2str(app.CallingApp.SigmaMaximum);
            app.MinimoGammaEditField.Value=int2str(app.CallingApp.GammaMinimum);
            app.MassimoGammaEditField.Value=int2str(app.CallingApp.GammaMaximum);
        end

        % Button pushed function: ConfermaButton
        function ConfermaButtonPushed(app, event)
            app.CallingApp.BetaMinimum=app.MinimoBetaEditField.Value;
            app.CallingApp.BetaMaximum=app.MassimoBetaEditField.Value;
            app.CallingApp.SigmaMinimum=app.MinimoSigmaEditField.Value;
            app.CallingApp.SigmaMaximum=app.MassimoSigmaEditField.Value;
            app.CallingApp.GammaMinimum=app.MinimoGammaEditField.Value;
            app.CallingApp.GammaMaximum=app.MassimoGammaEditField.Value;
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OpzioniScreen = uifigure('Visible', 'off');
            app.OpzioniScreen.Name = 'MATLAB App';
            app.ConfermaButton = uibutton(app.OpzioniScreen, 'push');
            app.ConfermaButton.ButtonPushedFcn = createCallbackFcn(app, @ConfermaButtonPushed, true);
            app.ConfermaButton.Text = 'Conferma';
            app.MinimoBetaEditFieldLabel = uilabel(app.OpzioniScreen);
            app.MinimoBetaEditFieldLabel.Text = 'Minimo Beta';
            app.MinimoBetaEditField = uieditfield(app.OpzioniScreen, 'text');
            app.MassimoBetaEditFieldLabel = uilabel(app.OpzioniScreen);
            app.MassimoBetaEditFieldLabel.Text = 'Massimo Beta';
            app.MassimoBetaEditField = uieditfield(app.OpzioniScreen, 'text');
            app.MinimoSigmaEditFieldLabel = uilabel(app.OpzioniScreen);
            app.MinimoSigmaEditFieldLabel.Text = 'Minimo Sigma';
            app.MinimoSigmaEditField = uieditfield(app.OpzioniScreen, 'text');
            app.MassimoSigmaEditFieldLabel = uilabel(app.OpzioniScreen);
            app.MassimoSigmaEditFieldLabel.Text = 'Massimo Sigma';
            app.MassimoSigmaEditField = uieditfield(app.OpzioniScreen, 'text');
            app.MinimoGammaEditFieldLabel = uilabel(app.OpzioniScreen);
            app.MinimoGammaEditFieldLabel.Text = 'Minimo Gamma';
            app.MinimoGammaEditField = uieditfield(app.OpzioniScreen, 'text');
            app.MassimoGammaEditFieldLabel = uilabel(app.OpzioniScreen);
            app.MassimoGammaEditFieldLabel.Text = 'Massimo Gamma';
            app.MassimoGammaEditField = uieditfield(app.OpzioniScreen, 'text');
            % Show the figure after all components are created
            app.OpzioniScreen.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OpzioniApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OpzioniScreen)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OpzioniScreen)
        end
    end
end